classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        LegendCheckBox                  matlab.ui.control.CheckBox
        SimulationfinishedLabel         matlab.ui.control.Label
        ShowstatisticsButton            matlab.ui.control.Button
        LiveplotsCheckBox               matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        STOPButton                      matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        UIAxes4                         matlab.ui.control.UIAxes
        OptionsPanel                    matlab.ui.container.Panel
        ProgramDropDownLabel            matlab.ui.control.Label
        ProgramDropDown                 matlab.ui.control.DropDown
        BackButton                      matlab.ui.control.Button
        CustomizeButton                 matlab.ui.control.StateButton
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        Odsetekosbchorychosabejodpornocix100Label  matlab.ui.control.Label
        LiczbaosbchorychzrzucanychodpoplacjinastartLabel  matlab.ui.control.Label
        Stosunekliczbywykonywanychtestwdorzeczywistej01Label  matlab.ui.control.Label
        Losowopojawiajacysiezarazeni1on0offLabel  matlab.ui.control.Label
        sick_pEditFieldLabel            matlab.ui.control.Label
        sick_pEditField                 matlab.ui.control.NumericEditField
        start_infEditFieldLabel         matlab.ui.control.Label
        start_infEditField              matlab.ui.control.NumericEditField
        testsEditFieldLabel             matlab.ui.control.Label
        testsEditField                  matlab.ui.control.NumericEditField
        rand_infEditFieldLabel          matlab.ui.control.Label
        rand_infEditField               matlab.ui.control.NumericEditField
        LoadPresetLabel                 matlab.ui.control.Label
        SelectpresetButtonGroup         matlab.ui.container.ButtonGroup
        GBPButton                       matlab.ui.control.RadioButton
        LoadButton                      matlab.ui.control.Button
        ProbabilityTab                  matlab.ui.container.Tab
        HQEditFieldLabel                matlab.ui.control.Label
        HQEditField                     matlab.ui.control.NumericEditField
        HSEditFieldLabel                matlab.ui.control.Label
        HSEditField                     matlab.ui.control.NumericEditField
        SQEditFieldLabel                matlab.ui.control.Label
        SQEditField                     matlab.ui.control.NumericEditField
        QHEditFieldLabel                matlab.ui.control.Label
        QHEditField                     matlab.ui.control.NumericEditField
        IQEditFieldLabel                matlab.ui.control.Label
        IQEditField                     matlab.ui.control.NumericEditField
        IISEditFieldLabel               matlab.ui.control.Label
        IISEditField                    matlab.ui.control.NumericEditField
        ISDEditFieldLabel               matlab.ui.control.Label
        ISDEditField                    matlab.ui.control.NumericEditField
        QDEditFieldLabel                matlab.ui.control.Label
        QDEditField                     matlab.ui.control.NumericEditField
        ISQEditFieldLabel               matlab.ui.control.Label
        ISQEditField                    matlab.ui.control.NumericEditField
        ISREditFieldLabel               matlab.ui.control.Label
        ISREditField                    matlab.ui.control.NumericEditField
        ISHEditFieldLabel               matlab.ui.control.Label
        ISHEditField                    matlab.ui.control.NumericEditField
        HDEditFieldLabel                matlab.ui.control.Label
        HDEditField                     matlab.ui.control.NumericEditField
        QHEditField_2Label              matlab.ui.control.Label
        QHEditField_2                   matlab.ui.control.NumericEditField
        HREditFieldLabel                matlab.ui.control.Label
        HREditField                     matlab.ui.control.NumericEditField
        IMPORTANTWAITFORTOOLTIPFORINFORMATIONLabel  matlab.ui.control.Label
        SHEditFieldLabel                matlab.ui.control.Label
        SHEditField                     matlab.ui.control.NumericEditField
        QSEditFieldLabel                matlab.ui.control.Label
        QSEditField                     matlab.ui.control.NumericEditField
        Get_infectedEditFieldLabel      matlab.ui.control.Label
        Get_infectedEditField           matlab.ui.control.EditField
        Infect_othersEditFieldLabel     matlab.ui.control.Label
        Infect_othersEditField          matlab.ui.control.EditField
        ChancestogetinfectedwhilebeinginspecyficstateLabel  matlab.ui.control.Label
        ChancestoinfectotherswhilebeinginspecyficstateLabel  matlab.ui.control.Label
        ReformsTab                      matlab.ui.container.Tab
        DaysofreformsEditFieldLabel     matlab.ui.control.Label
        DaysofreformsEditField          matlab.ui.control.EditField
        reformsplacedayofreformastimeafterfirstdayofsimulationLabel  matlab.ui.control.Label
        ReformefficiencyEditFieldLabel  matlab.ui.control.Label
        ReformefficiencyEditField       matlab.ui.control.EditField
        PopulationEditFieldLabel        matlab.ui.control.Label
        PopulationEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        GeneralTab_2                    matlab.ui.container.Tab
        N_typeEditFieldLabel            matlab.ui.control.Label
        N_typeEditField                 matlab.ui.control.NumericEditField
        Neighorhoodtype1Moore2NeumannLabel  matlab.ui.control.Label
        R_spawnEditFieldLabel           matlab.ui.control.Label
        R_spawnEditField                matlab.ui.control.NumericEditField
        SpawnrandominfecteduntillborderlockdowndayLabel  matlab.ui.control.Label
        B_lockEditFieldLabel            matlab.ui.control.Label
        B_lockEditField                 matlab.ui.control.NumericEditField
        BorderlockdowndayLabel          matlab.ui.control.Label
        N_testsEditFieldLabel           matlab.ui.control.Label
        N_testsEditField                matlab.ui.control.NumericEditField
        SumofteststopopulationratioLabel  matlab.ui.control.Label
        S_infEditFieldLabel             matlab.ui.control.Label
        S_infEditField                  matlab.ui.control.NumericEditField
        StartinginfectedLabel           matlab.ui.control.Label
        NS_caseEditFieldLabel           matlab.ui.control.Label
        NS_caseEditField                matlab.ui.control.NumericEditField
        PercentageofnosymptomscasesLabel  matlab.ui.control.Label
        ElderlyEditFieldLabel           matlab.ui.control.Label
        ElderlyEditField                matlab.ui.control.NumericEditField
        Peopleolderthan60yearsLabel     matlab.ui.control.Label
        H_riskEditFieldLabel            matlab.ui.control.Label
        H_riskEditField                 matlab.ui.control.NumericEditField
        PeopleinhighriskegchronicallyilllongtermsmokersLabel  matlab.ui.control.Label
        LoadPresetLabel_2               matlab.ui.control.Label
        SelectpresetButtonGroup_2       matlab.ui.container.ButtonGroup
        SWIButton                       matlab.ui.control.RadioButton
        LoadButton_2                    matlab.ui.control.Button
        CountrydataTab                  matlab.ui.container.Tab
        H_levelEditFieldLabel           matlab.ui.control.Label
        H_levelEditField                matlab.ui.control.NumericEditField
        CountryhealthcarelevelLabel     matlab.ui.control.Label
        D_levelEditFieldLabel           matlab.ui.control.Label
        D_levelEditField                matlab.ui.control.NumericEditField
        SocietydisciplinelevelLabel     matlab.ui.control.Label
        C_levelEditFieldLabel           matlab.ui.control.Label
        C_levelEditField                matlab.ui.control.NumericEditField
        GovermentcontrollevelLabel      matlab.ui.control.Label
        DensityEditFieldLabel           matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        PopulationdensityLabel          matlab.ui.control.Label
        Reform_dayEditFieldLabel        matlab.ui.control.Label
        Reform_dayEditField             matlab.ui.control.EditField
        Camp_dayEditFieldLabel          matlab.ui.control.Label
        Camp_dayEditField               matlab.ui.control.EditField
        DayofreformsWriteasdaysafterfirstdayofsimulationLabel  matlab.ui.control.Label
        DayofmediainformationincreaseLabel  matlab.ui.control.Label
        T_levelEditFieldLabel           matlab.ui.control.Label
        T_levelEditField                matlab.ui.control.NumericEditField
        LevelofcountrytourismLabel      matlab.ui.control.Label
        VirusdataTab                    matlab.ui.container.Tab
        AV_INCEditFieldLabel            matlab.ui.control.Label
        AV_INCEditField                 matlab.ui.control.NumericEditField
        AverageincubationtimeLabel      matlab.ui.control.Label
        AV_LTEditFieldLabel             matlab.ui.control.Label
        AV_LTEditField                  matlab.ui.control.NumericEditField
        AverageperiodofsicknessLabel    matlab.ui.control.Label
        AV_DTEditFieldLabel             matlab.ui.control.Label
        AV_DTEditField                  matlab.ui.control.NumericEditField
        AveragedeathrateLabel           matlab.ui.control.Label
        CT_LVLEditFieldLabel            matlab.ui.control.Label
        CT_LVLEditField                 matlab.ui.control.NumericEditField
        LevelofviruscontagiousnessLabel  matlab.ui.control.Label
        TabGroup3                       matlab.ui.container.TabGroup
        SIRTab                          matlab.ui.container.Tab
        TestrateEditFieldLabel          matlab.ui.control.Label
        TestrateEditField               matlab.ui.control.NumericEditField
        AlfaEditFieldLabel              matlab.ui.control.Label
        AlfaEditField                   matlab.ui.control.NumericEditField
        beta1EditFieldLabel             matlab.ui.control.Label
        beta1EditField                  matlab.ui.control.NumericEditField
        beta2EditFieldLabel             matlab.ui.control.Label
        beta2EditField                  matlab.ui.control.NumericEditField
        beta3EditFieldLabel             matlab.ui.control.Label
        beta3EditField                  matlab.ui.control.NumericEditField
        beta4EditFieldLabel             matlab.ui.control.Label
        beta4EditField                  matlab.ui.control.NumericEditField
        delta1EditFieldLabel            matlab.ui.control.Label
        delta1EditField                 matlab.ui.control.NumericEditField
        delta2EditFieldLabel            matlab.ui.control.Label
        delta2EditField                 matlab.ui.control.NumericEditField
        delta3EditFieldLabel            matlab.ui.control.Label
        delta3EditField                 matlab.ui.control.NumericEditField
        delta4EditFieldLabel            matlab.ui.control.Label
        delta4EditField                 matlab.ui.control.NumericEditField
        LoadpresetButtonGroup           matlab.ui.container.ButtonGroup
        NormalButton                    matlab.ui.control.RadioButton
        StrictButton                    matlab.ui.control.RadioButton
        LaxButton                       matlab.ui.control.RadioButton
        LoadButton_3                    matlab.ui.control.StateButton
        RerofmsLabel                    matlab.ui.control.Label
        Day1EditFieldLabel              matlab.ui.control.Label
        Day1EditField                   matlab.ui.control.NumericEditField
        Day2EditFieldLabel              matlab.ui.control.Label
        Day2EditField                   matlab.ui.control.NumericEditField
        Day3EditFieldLabel              matlab.ui.control.Label
        Day3EditField                   matlab.ui.control.NumericEditField
        val1                            matlab.ui.control.NumericEditField
        val2                            matlab.ui.control.NumericEditField
        val3                            matlab.ui.control.NumericEditField
        TimeLabel                       matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OptionsMenu
        function OptionsMenuSelected(app, event)
            app.OptionsPanel.Visible = 'On';
            app.Panel.Visible = 'Off';
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.OptionsPanel.Visible = 'Off';
            app.Panel.Visible = 'On';
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            %OPEN README.txt
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            addpath(genpath('LB'))
            addpath(genpath('PZ'))
            addpath(genpath('SIR'))
            app.StartButton.Visible = 'Off';
            app.UIAxes.cla;
            app.UIAxes3.cla;
            app.UIAxes4.cla;
            addpath(genpath('PZ'))
            app.UIAxes.XLim = [0 round(sqrt(app.PopulationEditField.Value))];
            app.UIAxes.YLim = [0 round(sqrt(app.PopulationEditField.Value))];
            app.ShowstatisticsButton.Visible = 'off';
            app.SimulationfinishedLabel.Visible = 'off';
             if app.ProgramDropDown.Value == "LB"
                 app.Panel_3.Visible = 'off';
                 gr = str2num(app.Reform_dayEditField.Value)
                 mr = str2num(app.Camp_dayEditField.Value)
                 wirusologia_fun(app.TimeEditField.Value, app.PopulationEditField.Value, app.N_typeEditField.Value, app.R_spawnEditField.Value, app.B_lockEditField.Value, app.T_levelEditField.Value, app.S_infEditField.Value, app.NS_caseEditField.Value, gr, mr, ...
                     app.ElderlyEditField.Value, app.H_riskEditField.Value, app.H_levelEditField.Value,app.D_levelEditField.Value,app.C_levelEditField.Value,app.DensityEditField.Value, app.T_levelEditField.Value, app.AV_INCEditField.Value, app.AV_LTEditField.Value, app.AV_DTEditField.Value,app.CT_LVLEditField.Value,app.UIAxes...
                     ,app.LiveplotsCheckBox.Value,app.UIAxes3);
             
             elseif app.ProgramDropDown.Value == "PZ"
                 app.Panel_3.Visible = 'off';
                 a = str2num(app.Get_infectedEditField.Value);
                 b = str2num(app.Infect_othersEditField.Value);
                 r1 = str2num(app.DaysofreformsEditField.Value);
                 r2 = str2num(app.ReformefficiencyEditField.Value);
                 chance = [b a];
                 REF = [r1;r2];
                 core_fun(app.PopulationEditField.Value,app.TimeEditField.Value,app.sick_pEditField.Value, app.start_infEditField.Value,app.testsEditField.Value, app.rand_infEditField.Value, app.HQEditField.Value,app.HSEditField.Value, app.SQEditField.Value, app.SHEditField.Value, app.QSEditField.Value,...
                     app.QHEditField.Value, app.IQEditField.Value,app.IISEditField.Value,app.ISDEditField.Value,app.QDEditField.Value,app.ISQEditField.Value,app.ISREditField.Value,app.ISHEditField.Value, app.HDEditField.Value,app.HREditField.Value,chance,REF,app.UIAxes,app.UIAxes2,app.LegendCheckBox.Value,...
                     app.UIAxes3,app.LiveplotsCheckBox.Value);                 
             else
                 app.Panel_3.Visible = 'on';
                 assignin('base','starting_pop', app.PopulationEditField.Value)
                 assignin('base','test_ratio', app.TestrateEditField.Value)
                 assignin('base','alfa', app.AlfaEditField.Value)
                 assignin('base','beta1', app.beta1EditField.Value)
                 assignin('base','beta2', app.beta2EditField.Value)
                 assignin('base','beta3', app.beta3EditField.Value)
                 assignin('base','beta4', app.beta4EditField.Value)
                 assignin('base','delta1', app.delta1EditField.Value)
                 assignin('base','delta2', app.delta2EditField.Value)
                 assignin('base','delta3', app.delta3EditField.Value)
                 assignin('base','delta4', app.delta4EditField.Value)
                 assignin('base','d1', app.Day1EditField.Value)
                 assignin('base','d2', app.Day2EditField.Value)
                 assignin('base','d3', app.Day3EditField.Value)
                 assignin('base','v1', app.val1.Value)
                 assignin('base','v2', app.val2.Value)
                 assignin('base','v3', app.val3.Value)
                 SIRSIM1(app.UIAxes4,app.TimeEditField.Value, app.AlfaEditField.Value, app.beta1EditField.Value, app.beta2EditField.Value, app.beta3EditField.Value, app.beta4EditField.Value, app.delta1EditField.Value, app.delta2EditField.Value, app.delta3EditField.Value, app.delta4EditField.Value, app.PopulationEditField.Value, app.TestrateEditField.Value);
             end
            app.ShowstatisticsButton.Visible = 'on';
            app.SimulationfinishedLabel.Visible = 'on';
            app.StartButton.Visible = 'On';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if app.GBPButton.Value
                app.sick_pEditField.Value = 0.01;
                app.testsEditField.Value = 1;
                app.start_infEditField.Value = 1;
                app.rand_infEditField.Value = 1;
                app.Infect_othersEditField.Value='[0;0.05;0.3;0;0.4;0.3;0;0]';
                app.Get_infectedEditField.Value = '[0.1;0.01;0;0.15;0;0;0;0]';
                app.HQEditField.Value = 0.0008;
                app.HSEditField.Value = 0.001;
                app.SQEditField.Value = 0.02;
                app.QHEditField.Value = 0.02;
                app.SHEditField.Value = 0.004;
                app.QSEditField.Value = 0.005;
                app.IQEditField.Value = app.testsEditField.Value*0.005;
                app.IISEditField.Value = 0.05;
                app.ISDEditField.Value = 0.03;
                app.QDEditField.Value = 0;
                app.ISQEditField.Value = 0;
                app.ISREditField.Value = 0.01;
                app.ISHEditField.Value = 0.1;
                app.HDEditField.Value = 0.02;
                app.QHEditField.Value = 0;
                app.HREditField.Value = 0.8;
                app.DaysofreformsEditField.Value = '[1 11 18 20 23 30 40]';
                app.ReformefficiencyEditField.Value = '[0.03 0.035 0.04 0.045 0.05 0.055 0.06]';
            end
        end

        % Value changed function: CustomizeButton
        function CustomizeButtonValueChanged(app, event)
            value = app.CustomizeButton.Value;
            if value && app.ProgramDropDown.Value == "PZ" && app.OptionsPanel.Visible == "on"
                app.TabGroup.Visible = 'On';
                app.TimeEditField.Visible = 'Off';
                app.TimeEditFieldLabel.Visible = 'Off';
                app.PopulationEditField.Visible = 'Off';
                app.PopulationEditFieldLabel.Visible = 'Off';
            elseif value && app.ProgramDropDown.Value == "LB" && app.OptionsPanel.Visible == "on"
                app.TabGroup2.Visible = 'On';
                app.TimeEditField.Visible = 'Off';
                app.TimeEditFieldLabel.Visible = 'Off';
                app.PopulationEditField.Visible = 'Off';
                app.PopulationEditFieldLabel.Visible = 'Off';
            elseif value && app.ProgramDropDown.Value == "SIR" && app.OptionsPanel.Visible == "on"
                app.TabGroup3.Visible = 'On';
                app.TimeEditField.Visible = 'Off';
                app.TimeEditFieldLabel.Visible = 'Off';
                app.PopulationEditField.Visible = 'Off';
                app.PopulationEditFieldLabel.Visible = 'Off';
            else
                app.TabGroup.Visible = 'Off';
                app.TabGroup2.Visible = 'Off';
                app.TabGroup3.Visible = 'Off';
                app.TimeEditField.Visible = 'On';
                app.TimeEditFieldLabel.Visible = 'On';
                app.PopulationEditField.Visible = 'On';
                app.PopulationEditFieldLabel.Visible = 'On';
            end
            
        end

        % Value changed function: ProgramDropDown
        function ProgramDropDownValueChanged(app, event)
            %value = app.ProgramDropDown.Value;
            app.CustomizeButton.Value = false;
            app.TabGroup.Visible = 'Off';
            app.TabGroup2.Visible = 'Off';
            app.TimeEditField.Visible = 'On';
            app.TimeEditFieldLabel.Visible = 'On';
            app.PopulationEditField.Visible = 'On';
            app.PopulationEditFieldLabel.Visible = 'On';
        end

        % Button pushed function: ShowstatisticsButton
        function ShowstatisticsButtonPushed(app, event)
            app.LegendCheckBox.Value;
            
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            value = app.LegendCheckBox.Value;
            addpath(genpath('PZ'))
            if app.ProgramDropDown.Value == "PZ"
                leg = imread('legend.png');
                if value
                    k = app.Panel.Position;
                    app.UIAxes.Position = [k(1) k(4)/2 k(3)-160 k(4)/2];
                    app.UIAxes2.Position = [k(3)-160 k(4)/2 160 k(4)/2];
                    app.UIAxes2.Visible = 'on';
                    imshow(leg,'Parent',app.UIAxes2);
                elseif ~value
                    app.UIAxes2.cla;
                    app.UIAxes2.Visible = 'Off';
                    k = app.Panel.Position;
                    app.UIAxes.Position = [k(1) k(4)/2 k(3) k(4)/2];
                end
            end
        end

        % Value changed function: LiveplotsCheckBox
        function LiveplotsCheckBoxValueChanged(app, event)
            value = app.LiveplotsCheckBox.Value;
            if value 
                app.Panel_2.Visible = 'on';
            else
                app.Panel_2.Visible = 'off';
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            if app.SWIButton.Value
                app.PopulationEditField.Value = 3000000;
                app.TimeEditField.Value = 50;
                app.N_typeEditField.Value = 1;
                app.R_spawnEditField.Value = 1;
                app.B_lockEditField.Value = 16;
                app.N_testsEditField.Value = 0.1;
                app.S_infEditField.Value = 1;
                app.NS_caseEditField.Value = 0.25;
                app.ElderlyEditField.Value = 0.05;
                app.H_riskEditField.Value = 0.02;
                app.H_levelEditField.Value = 3;
                app.D_levelEditField.Value = 70;
                app.DensityEditField.Value = 30;
                app.T_levelEditField.Value = 1;
                app.Reform_dayEditField.Value = "[11 18 21 25]";
                app.Camp_dayEditField.Value = "[11]";   
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            clear sound
            clear all
        end

        % Value changed function: LoadButton_3
        function LoadButton_3ValueChanged(app, event)
            value = app.LoadButton_3.Value;
            if app.NormalButton.Value
                app.PopulationEditField.Value = 1000000;
                app.TimeEditField.Value = 200;
                app.AlfaEditField.Value = 0.000004;
                app.beta1EditField.Value = 0.05;
                app.beta2EditField.Value = 0.07;
                app.beta3EditField.Value = 0.05;
                app.beta4EditField.Value = 0.07;
                app.delta1EditField.Value = 0.01;
                app.delta2EditField.Value = 0.02;
                app.delta3EditField.Value = 0.01;
                app.delta4EditField.Value = 0.02;
                app.Day1EditField.Value = 20;
                app.val1.Value = 0.9;
                app.Day2EditField.Value = 40;
                app.val2.Value = 0.7;
                app.Day3EditField.Value = 60;
                app.val3.Value = 0.4;
            end
            if app.StrictButton.Value
                app.PopulationEditField.Value = 1000000;
                app.TimeEditField.Value = 200;
                app.AlfaEditField.Value = 0.000015;
                app.beta1EditField.Value = 0.05;
                app.beta2EditField.Value = 0.07;
                app.beta3EditField.Value = 0.05;
                app.beta4EditField.Value = 0.07;
                app.delta1EditField.Value = 0.01;
                app.delta2EditField.Value = 0.01;
                app.delta3EditField.Value = 0.01;
                app.delta4EditField.Value = 0.01;
                app.Day1EditField.Value = 20;
                app.val1.Value = 0.3;
                app.Day2EditField.Value = 30;
                app.val2.Value = 0.1;
                app.Day3EditField.Value = 0;
                app.val3.Value = 0;
            end
            if app.LaxButton.Value
                app.PopulationEditField.Value = 1000000;
                app.TimeEditField.Value = 200;
                app.AlfaEditField.Value = 0.000015;
                app.beta1EditField.Value = 0.05;
                app.beta2EditField.Value = 0.07;
                app.beta3EditField.Value = 0.05;
                app.beta4EditField.Value = 0.07;
                app.delta1EditField.Value = 0.02;
                app.delta2EditField.Value = 0.01;
                app.delta3EditField.Value = 0.03;
                app.delta4EditField.Value = 0.01;
                app.Day1EditField.Value = 40;
                app.val1.Value = 0.9;
                app.Day2EditField.Value = 600;
                app.val2.Value = 0.8;
                app.Day3EditField.Value = 0;
                app.val3.Value = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OptionsMenu = uimenu(app.Menu);
            app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.OptionsMenu.Text = 'Options';
            app.HelpMenu = uimenu(app.Menu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.Panel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.UIAxes2 = uiaxes(app.Panel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.LegendCheckBox = uicheckbox(app.Panel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.SimulationfinishedLabel = uilabel(app.Panel);
            app.SimulationfinishedLabel.Visible = 'off';
            app.SimulationfinishedLabel.Text = 'Simulation finished!';
            app.ShowstatisticsButton = uibutton(app.Panel, 'push');
            app.ShowstatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowstatisticsButtonPushed, true);
            app.ShowstatisticsButton.Visible = 'off';
            app.ShowstatisticsButton.Text = 'Show statistics';
            app.LiveplotsCheckBox = uicheckbox(app.Panel);
            app.LiveplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveplotsCheckBoxValueChanged, true);
            app.LiveplotsCheckBox.Text = 'Live plots';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.UIAxes3 = uiaxes(app.Panel_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.STOPButton = uibutton(app.Panel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Visible = 'off';
            app.STOPButton.Text = 'STOP';
            app.Panel_3 = uipanel(app.Panel);
            app.Panel_3.Visible = 'off';
            app.UIAxes4 = uiaxes(app.Panel_3);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.OptionsPanel.Visible = 'off';
            app.ProgramDropDownLabel = uilabel(app.OptionsPanel);
            app.ProgramDropDownLabel.Text = 'Program:';
            app.ProgramDropDown = uidropdown(app.OptionsPanel);
            app.ProgramDropDown.Items = {'PZ', 'LB', 'SIR'};
            app.ProgramDropDown.ItemsData = {'PZ', 'LB', 'SIR', ''};
            app.ProgramDropDown.ValueChangedFcn = createCallbackFcn(app, @ProgramDropDownValueChanged, true);
            app.ProgramDropDown.Value = 'PZ';
            app.BackButton = uibutton(app.OptionsPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.CustomizeButton = uibutton(app.OptionsPanel, 'state');
            app.CustomizeButton.ValueChangedFcn = createCallbackFcn(app, @CustomizeButtonValueChanged, true);
            app.CustomizeButton.Text = 'Customize';
            app.TabGroup = uitabgroup(app.OptionsPanel);
            app.TabGroup.Visible = 'off';
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.Odsetekosbchorychosabejodpornocix100Label = uilabel(app.GeneralTab);
            app.Odsetekosbchorychosabejodpornocix100Label.Text = 'Odsetek osób chorych/ o słabej odporności x%/100';
            app.LiczbaosbchorychzrzucanychodpoplacjinastartLabel = uilabel(app.GeneralTab);
            app.LiczbaosbchorychzrzucanychodpoplacjinastartLabel.Text = 'Liczba osób chorych zrzucanych od poplacji na start';
            app.Stosunekliczbywykonywanychtestwdorzeczywistej01Label = uilabel(app.GeneralTab);
            app.Stosunekliczbywykonywanychtestwdorzeczywistej01Label.Text = 'Stosunek liczby wykonywanych testów do rzeczywistej <0,1>';
            app.Losowopojawiajacysiezarazeni1on0offLabel = uilabel(app.GeneralTab);
            app.Losowopojawiajacysiezarazeni1on0offLabel.Text = 'Losowo pojawiajacy sie zarazeni 1 - on, 0 - off';
            app.sick_pEditFieldLabel = uilabel(app.GeneralTab);
            app.sick_pEditFieldLabel.Text = 'sick_p';
            app.sick_pEditField = uieditfield(app.GeneralTab, 'numeric');
            app.start_infEditFieldLabel = uilabel(app.GeneralTab);
            app.start_infEditFieldLabel.Text = 'start_inf';
            app.start_infEditField = uieditfield(app.GeneralTab, 'numeric');
            app.testsEditFieldLabel = uilabel(app.GeneralTab);
            app.testsEditFieldLabel.Text = 'tests';
            app.testsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.rand_infEditFieldLabel = uilabel(app.GeneralTab);
            app.rand_infEditFieldLabel.Text = 'rand_inf';
            app.rand_infEditField = uieditfield(app.GeneralTab, 'numeric');
            app.LoadPresetLabel = uilabel(app.GeneralTab);
            app.LoadPresetLabel.Text = 'Load Preset';
            app.SelectpresetButtonGroup = uibuttongroup(app.GeneralTab);
            app.SelectpresetButtonGroup.Title = 'Select preset';
            app.GBPButton = uiradiobutton(app.SelectpresetButtonGroup);
            app.GBPButton.Text = 'GBP';
            app.GBPButton.Value = true;
            app.LoadButton = uibutton(app.GeneralTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ProbabilityTab = uitab(app.TabGroup);
            app.ProbabilityTab.Title = 'Probability';
            app.HQEditFieldLabel = uilabel(app.ProbabilityTab);
            app.HQEditFieldLabel.Text = 'HQ';
            app.HQEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.HQEditField.Tooltip = {'healty ->in_quarantine'};
            app.HSEditFieldLabel = uilabel(app.ProbabilityTab);
            app.HSEditFieldLabel.Text = 'HS';
            app.HSEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.HSEditField.Tooltip = {'healty -> sick'};
            app.SQEditFieldLabel = uilabel(app.ProbabilityTab);
            app.SQEditFieldLabel.Text = 'SQ';
            app.SQEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.SQEditField.Tooltip = {'sick -> in_quarantine'};
            app.QHEditFieldLabel = uilabel(app.ProbabilityTab);
            app.QHEditFieldLabel.Text = 'QH';
            app.QHEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.QHEditField.Tooltip = {'in_quarantine -> healthy'};
            app.IQEditFieldLabel = uilabel(app.ProbabilityTab);
            app.IQEditFieldLabel.Text = 'IQ';
            app.IQEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.IQEditField.Tooltip = {'infected -> in_quarantine'};
            app.IISEditFieldLabel = uilabel(app.ProbabilityTab);
            app.IISEditFieldLabel.Text = 'IIS';
            app.IISEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.IISEditField.Tooltip = {'infected -> infected_and_sick'};
            app.ISDEditFieldLabel = uilabel(app.ProbabilityTab);
            app.ISDEditFieldLabel.Text = 'ISD';
            app.ISDEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.ISDEditField.Tooltip = {'infected_and_sick -> dead'};
            app.QDEditFieldLabel = uilabel(app.ProbabilityTab);
            app.QDEditFieldLabel.Text = 'QD';
            app.QDEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.QDEditField.Tooltip = {'in_quarantine -> dead'};
            app.ISQEditFieldLabel = uilabel(app.ProbabilityTab);
            app.ISQEditFieldLabel.Text = 'ISQ';
            app.ISQEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.ISQEditField.Tooltip = {'infected_and_sick -> in_quarantine'};
            app.ISREditFieldLabel = uilabel(app.ProbabilityTab);
            app.ISREditFieldLabel.Text = 'ISR';
            app.ISREditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.ISREditField.Tooltip = {'infected_and_sick -> recovered'};
            app.ISHEditFieldLabel = uilabel(app.ProbabilityTab);
            app.ISHEditFieldLabel.Text = 'ISH';
            app.ISHEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.ISHEditField.Tooltip = {'infected_and_sick -> in_hospital'};
            app.HDEditFieldLabel = uilabel(app.ProbabilityTab);
            app.HDEditFieldLabel.Text = 'HD';
            app.HDEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.HDEditField.Tooltip = {'in_hospital -> dead'};
            app.QHEditField_2Label = uilabel(app.ProbabilityTab);
            app.QHEditField_2Label.Text = 'QH';
            app.QHEditField_2 = uieditfield(app.ProbabilityTab, 'numeric');
            app.QHEditField_2.Tooltip = {'in_quarantine -> in_hospital'};
            app.HREditFieldLabel = uilabel(app.ProbabilityTab);
            app.HREditFieldLabel.Text = 'HR';
            app.HREditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.HREditField.Tooltip = {'in_hospital -> recovered'};
            app.IMPORTANTWAITFORTOOLTIPFORINFORMATIONLabel = uilabel(app.ProbabilityTab);
            app.IMPORTANTWAITFORTOOLTIPFORINFORMATIONLabel.Text = 'IMPORTANT: WAIT FOR TOOLTIP FOR INFORMATION';
            app.SHEditFieldLabel = uilabel(app.ProbabilityTab);
            app.SHEditFieldLabel.Text = 'SH';
            app.SHEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.SHEditField.Tooltip = {'sick -> healthy'};
            app.QSEditFieldLabel = uilabel(app.ProbabilityTab);
            app.QSEditFieldLabel.Text = 'QS';
            app.QSEditField = uieditfield(app.ProbabilityTab, 'numeric');
            app.QSEditField.Tooltip = {'in_quarantine -> sick'};
            app.Get_infectedEditFieldLabel = uilabel(app.ProbabilityTab);
            app.Get_infectedEditFieldLabel.Text = 'Get_infected';
            app.Get_infectedEditField = uieditfield(app.ProbabilityTab, 'text');
            app.Get_infectedEditField.Value = '[0;0;0;0;0;0;0;0]';
            app.Infect_othersEditFieldLabel = uilabel(app.ProbabilityTab);
            app.Infect_othersEditFieldLabel.Text = 'Infect_others';
            app.Infect_othersEditField = uieditfield(app.ProbabilityTab, 'text');
            app.Infect_othersEditField.Value = '[0;0;0;0;0;0;0;0]';
            app.ChancestogetinfectedwhilebeinginspecyficstateLabel = uilabel(app.ProbabilityTab);
            app.ChancestogetinfectedwhilebeinginspecyficstateLabel.Text = 'Chances to get infected while being in specyfic state';
            app.ChancestoinfectotherswhilebeinginspecyficstateLabel = uilabel(app.ProbabilityTab);
            app.ChancestoinfectotherswhilebeinginspecyficstateLabel.Text = 'Chances to infect others while being in specyfic state';
            app.ReformsTab = uitab(app.TabGroup);
            app.ReformsTab.Title = 'Reforms';
            app.DaysofreformsEditFieldLabel = uilabel(app.ReformsTab);
            app.DaysofreformsEditFieldLabel.Text = 'Days of reforms';
            app.DaysofreformsEditField = uieditfield(app.ReformsTab, 'text');
            app.DaysofreformsEditField.Value = '[0 0 0 0 0 0 0]';
            app.reformsplacedayofreformastimeafterfirstdayofsimulationLabel = uilabel(app.ReformsTab);
            app.reformsplacedayofreformastimeafterfirstdayofsimulationLabel.Text = '7 reforms, place day of reform as time after first  day of simulation';
            app.ReformefficiencyEditFieldLabel = uilabel(app.ReformsTab);
            app.ReformefficiencyEditFieldLabel.Text = 'Reform efficiency';
            app.ReformefficiencyEditField = uieditfield(app.ReformsTab, 'text');
            app.ReformefficiencyEditField.Value = '[0 0 0 0 0 0 0]';
            app.PopulationEditFieldLabel = uilabel(app.OptionsPanel);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.PopulationEditField.Value = 10000;
            app.TimeEditFieldLabel = uilabel(app.OptionsPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TimeEditField.Value = 20;
            app.TabGroup2 = uitabgroup(app.OptionsPanel);
            app.TabGroup2.Visible = 'off';
            app.GeneralTab_2 = uitab(app.TabGroup2);
            app.GeneralTab_2.Title = 'General';
            app.N_typeEditFieldLabel = uilabel(app.GeneralTab_2);
            app.N_typeEditFieldLabel.Text = 'N_type';
            app.N_typeEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.N_typeEditField.Value = 1;
            app.Neighorhoodtype1Moore2NeumannLabel = uilabel(app.GeneralTab_2);
            app.Neighorhoodtype1Moore2NeumannLabel.Text = 'Neighorhood type 1-Moore, 2-Neumann.';
            app.R_spawnEditFieldLabel = uilabel(app.GeneralTab_2);
            app.R_spawnEditFieldLabel.Text = 'R_spawn';
            app.R_spawnEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.SpawnrandominfecteduntillborderlockdowndayLabel = uilabel(app.GeneralTab_2);
            app.SpawnrandominfecteduntillborderlockdowndayLabel.Text = 'Spawn random infected untill border lockdown day.';
            app.B_lockEditFieldLabel = uilabel(app.GeneralTab_2);
            app.B_lockEditFieldLabel.Text = 'B_lock';
            app.B_lockEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.BorderlockdowndayLabel = uilabel(app.GeneralTab_2);
            app.BorderlockdowndayLabel.Text = 'Border lockdown day.';
            app.N_testsEditFieldLabel = uilabel(app.GeneralTab_2);
            app.N_testsEditFieldLabel.Text = 'N_tests';
            app.N_testsEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.N_testsEditField.Value = 0.1;
            app.SumofteststopopulationratioLabel = uilabel(app.GeneralTab_2);
            app.SumofteststopopulationratioLabel.Text = 'Sum of tests to population ratio. ';
            app.S_infEditFieldLabel = uilabel(app.GeneralTab_2);
            app.S_infEditFieldLabel.Text = 'S_inf';
            app.S_infEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.S_infEditField.Value = 1;
            app.StartinginfectedLabel = uilabel(app.GeneralTab_2);
            app.StartinginfectedLabel.Text = 'Starting infected.';
            app.NS_caseEditFieldLabel = uilabel(app.GeneralTab_2);
            app.NS_caseEditFieldLabel.Text = 'NS_case';
            app.NS_caseEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.NS_caseEditField.Value = 0.25;
            app.PercentageofnosymptomscasesLabel = uilabel(app.GeneralTab_2);
            app.PercentageofnosymptomscasesLabel.Text = 'Percentage of no symptoms cases.';
            app.ElderlyEditFieldLabel = uilabel(app.GeneralTab_2);
            app.ElderlyEditFieldLabel.Text = 'Elderly';
            app.ElderlyEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.ElderlyEditField.Value = 0.02;
            app.Peopleolderthan60yearsLabel = uilabel(app.GeneralTab_2);
            app.Peopleolderthan60yearsLabel.Text = 'People older than 60 years.';
            app.H_riskEditFieldLabel = uilabel(app.GeneralTab_2);
            app.H_riskEditFieldLabel.Text = 'H_risk';
            app.H_riskEditField = uieditfield(app.GeneralTab_2, 'numeric');
            app.H_riskEditField.Value = 0.02;
            app.PeopleinhighriskegchronicallyilllongtermsmokersLabel = uilabel(app.GeneralTab_2);
            app.PeopleinhighriskegchronicallyilllongtermsmokersLabel.Text = 'People in high risk, eg. chronically ill, long-term smokers.';
            app.LoadPresetLabel_2 = uilabel(app.GeneralTab_2);
            app.LoadPresetLabel_2.Text = 'Load Preset';
            app.SelectpresetButtonGroup_2 = uibuttongroup(app.GeneralTab_2);
            app.SelectpresetButtonGroup_2.Title = 'Select preset';
            app.SWIButton = uiradiobutton(app.SelectpresetButtonGroup_2);
            app.SWIButton.Text = 'SWI';
            app.SWIButton.Value = true;
            app.LoadButton_2 = uibutton(app.GeneralTab_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.CountrydataTab = uitab(app.TabGroup2);
            app.CountrydataTab.Title = 'Country data';
            app.H_levelEditFieldLabel = uilabel(app.CountrydataTab);
            app.H_levelEditFieldLabel.Text = 'H_level';
            app.H_levelEditField = uieditfield(app.CountrydataTab, 'numeric');
            app.H_levelEditField.Value = 1;
            app.CountryhealthcarelevelLabel = uilabel(app.CountrydataTab);
            app.CountryhealthcarelevelLabel.Text = 'Country healthcare level';
            app.D_levelEditFieldLabel = uilabel(app.CountrydataTab);
            app.D_levelEditFieldLabel.Text = 'D_level';
            app.D_levelEditField = uieditfield(app.CountrydataTab, 'numeric');
            app.D_levelEditField.Value = 70;
            app.SocietydisciplinelevelLabel = uilabel(app.CountrydataTab);
            app.SocietydisciplinelevelLabel.Text = 'Society discipline level';
            app.C_levelEditFieldLabel = uilabel(app.CountrydataTab);
            app.C_levelEditFieldLabel.Text = 'C_level';
            app.C_levelEditField = uieditfield(app.CountrydataTab, 'numeric');
            app.C_levelEditField.Value = 2;
            app.GovermentcontrollevelLabel = uilabel(app.CountrydataTab);
            app.GovermentcontrollevelLabel.Text = 'Goverment control level';
            app.DensityEditFieldLabel = uilabel(app.CountrydataTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.CountrydataTab, 'numeric');
            app.DensityEditField.Value = 30;
            app.PopulationdensityLabel = uilabel(app.CountrydataTab);
            app.PopulationdensityLabel.Text = 'Population density.';
            app.Reform_dayEditFieldLabel = uilabel(app.CountrydataTab);
            app.Reform_dayEditFieldLabel.Text = 'Reform_day';
            app.Reform_dayEditField = uieditfield(app.CountrydataTab, 'text');
            app.Reform_dayEditField.Value = '[0 0 0 0 0]';
            app.Camp_dayEditFieldLabel = uilabel(app.CountrydataTab);
            app.Camp_dayEditFieldLabel.Text = 'Camp_day';
            app.Camp_dayEditField = uieditfield(app.CountrydataTab, 'text');
            app.Camp_dayEditField.Value = '[0]';
            app.DayofreformsWriteasdaysafterfirstdayofsimulationLabel = uilabel(app.CountrydataTab);
            app.DayofreformsWriteasdaysafterfirstdayofsimulationLabel.Text = 'Day of reforms. Write as days after first day of simulation.';
            app.DayofmediainformationincreaseLabel = uilabel(app.CountrydataTab);
            app.DayofmediainformationincreaseLabel.Text = 'Day of media information increase.';
            app.T_levelEditFieldLabel = uilabel(app.CountrydataTab);
            app.T_levelEditFieldLabel.Text = 'T_level';
            app.T_levelEditField = uieditfield(app.CountrydataTab, 'numeric');
            app.T_levelEditField.Value = 2;
            app.LevelofcountrytourismLabel = uilabel(app.CountrydataTab);
            app.LevelofcountrytourismLabel.Text = 'Level of country tourism. ';
            app.VirusdataTab = uitab(app.TabGroup2);
            app.VirusdataTab.Title = 'Virus data';
            app.AV_INCEditFieldLabel = uilabel(app.VirusdataTab);
            app.AV_INCEditFieldLabel.Text = 'AV_INC';
            app.AV_INCEditField = uieditfield(app.VirusdataTab, 'numeric');
            app.AV_INCEditField.Value = 5;
            app.AverageincubationtimeLabel = uilabel(app.VirusdataTab);
            app.AverageincubationtimeLabel.Text = 'Average incubation time.';
            app.AV_LTEditFieldLabel = uilabel(app.VirusdataTab);
            app.AV_LTEditFieldLabel.Text = 'AV_LT';
            app.AV_LTEditField = uieditfield(app.VirusdataTab, 'numeric');
            app.AV_LTEditField.Value = 28;
            app.AverageperiodofsicknessLabel = uilabel(app.VirusdataTab);
            app.AverageperiodofsicknessLabel.Text = 'Average period of sickness.';
            app.AV_DTEditFieldLabel = uilabel(app.VirusdataTab);
            app.AV_DTEditFieldLabel.Text = 'AV_DT';
            app.AV_DTEditField = uieditfield(app.VirusdataTab, 'numeric');
            app.AV_DTEditField.Value = 0.03;
            app.AveragedeathrateLabel = uilabel(app.VirusdataTab);
            app.AveragedeathrateLabel.Text = 'Average death rate.';
            app.CT_LVLEditFieldLabel = uilabel(app.VirusdataTab);
            app.CT_LVLEditFieldLabel.Text = 'CT_LVL';
            app.CT_LVLEditField = uieditfield(app.VirusdataTab, 'numeric');
            app.CT_LVLEditField.Value = 0.25;
            app.LevelofviruscontagiousnessLabel = uilabel(app.VirusdataTab);
            app.LevelofviruscontagiousnessLabel.Text = 'Level of virus contagiousness ';
            app.TabGroup3 = uitabgroup(app.OptionsPanel);
            app.TabGroup3.Visible = 'off';
            app.SIRTab = uitab(app.TabGroup3);
            app.SIRTab.Title = 'SIR';
            app.TestrateEditFieldLabel = uilabel(app.SIRTab);
            app.TestrateEditFieldLabel.Text = 'Test rate';
            app.TestrateEditField = uieditfield(app.SIRTab, 'numeric');
            app.TestrateEditField.Value = 1;
            app.AlfaEditFieldLabel = uilabel(app.SIRTab);
            app.AlfaEditFieldLabel.Text = 'Alfa';
            app.AlfaEditField = uieditfield(app.SIRTab, 'numeric');
            app.beta1EditFieldLabel = uilabel(app.SIRTab);
            app.beta1EditFieldLabel.Text = 'beta1';
            app.beta1EditField = uieditfield(app.SIRTab, 'numeric');
            app.beta2EditFieldLabel = uilabel(app.SIRTab);
            app.beta2EditFieldLabel.Text = 'beta2';
            app.beta2EditField = uieditfield(app.SIRTab, 'numeric');
            app.beta3EditFieldLabel = uilabel(app.SIRTab);
            app.beta3EditFieldLabel.Text = 'beta3';
            app.beta3EditField = uieditfield(app.SIRTab, 'numeric');
            app.beta4EditFieldLabel = uilabel(app.SIRTab);
            app.beta4EditFieldLabel.Text = 'beta4';
            app.beta4EditField = uieditfield(app.SIRTab, 'numeric');
            app.delta1EditFieldLabel = uilabel(app.SIRTab);
            app.delta1EditFieldLabel.Text = 'delta1';
            app.delta1EditField = uieditfield(app.SIRTab, 'numeric');
            app.delta2EditFieldLabel = uilabel(app.SIRTab);
            app.delta2EditFieldLabel.Text = 'delta2';
            app.delta2EditField = uieditfield(app.SIRTab, 'numeric');
            app.delta3EditFieldLabel = uilabel(app.SIRTab);
            app.delta3EditFieldLabel.Text = 'delta3';
            app.delta3EditField = uieditfield(app.SIRTab, 'numeric');
            app.delta4EditFieldLabel = uilabel(app.SIRTab);
            app.delta4EditFieldLabel.Text = 'delta4';
            app.delta4EditField = uieditfield(app.SIRTab, 'numeric');
            app.LoadpresetButtonGroup = uibuttongroup(app.SIRTab);
            app.LoadpresetButtonGroup.Title = 'Load preset';
            app.NormalButton = uiradiobutton(app.LoadpresetButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.StrictButton = uiradiobutton(app.LoadpresetButtonGroup);
            app.StrictButton.Text = 'Strict';
            app.LaxButton = uiradiobutton(app.LoadpresetButtonGroup);
            app.LaxButton.Text = 'Lax';
            app.LoadButton_3 = uibutton(app.SIRTab, 'state');
            app.LoadButton_3.ValueChangedFcn = createCallbackFcn(app, @LoadButton_3ValueChanged, true);
            app.LoadButton_3.Text = 'Load';
            app.RerofmsLabel = uilabel(app.SIRTab);
            app.RerofmsLabel.Text = 'Rerofms:';
            app.Day1EditFieldLabel = uilabel(app.SIRTab);
            app.Day1EditFieldLabel.Text = 'Day1';
            app.Day1EditField = uieditfield(app.SIRTab, 'numeric');
            app.Day2EditFieldLabel = uilabel(app.SIRTab);
            app.Day2EditFieldLabel.Text = 'Day2';
            app.Day2EditField = uieditfield(app.SIRTab, 'numeric');
            app.Day3EditFieldLabel = uilabel(app.SIRTab);
            app.Day3EditFieldLabel.Text = 'Day3';
            app.Day3EditField = uieditfield(app.SIRTab, 'numeric');
            app.val1 = uieditfield(app.SIRTab, 'numeric');
            app.val2 = uieditfield(app.SIRTab, 'numeric');
            app.val3 = uieditfield(app.SIRTab, 'numeric');
            app.TimeLabel = uilabel(app.SIRTab);
            app.TimeLabel.Text = 'Time';
            app.ValueLabel = uilabel(app.SIRTab);
            app.ValueLabel.Text = 'Value';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end