classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DropDown                  matlab.ui.control.DropDown
        TabGroup2                 matlab.ui.container.TabGroup
        DataTab                   matlab.ui.container.Tab
        TabGroup                  matlab.ui.container.TabGroup
        AllDataTab                matlab.ui.container.Tab
        UITable2                  matlab.ui.control.Table
        FrequencyTab              matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
        CompDataTab               matlab.ui.container.Tab
        UITable3                  matlab.ui.control.Table
        DataGraficTab             matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        DegreeSpinnerLabel        matlab.ui.control.Label
        DegreeSpinner             matlab.ui.control.Spinner
        averageLabel              matlab.ui.control.Label
        LoadfromfileButton        matlab.ui.control.Button
        SavetofileButton          matlab.ui.control.Button
        AddRowButton              matlab.ui.control.Button
        RemoveRowButton           matlab.ui.control.Button
        RefreshButton             matlab.ui.control.Button
        ParameterTab              matlab.ui.container.Tab
        UITable4                  matlab.ui.control.Table
        iSpinnerLabel             matlab.ui.control.Label
        iSpinner                  matlab.ui.control.Spinner
        StartButton               matlab.ui.control.Button
        SystemDropDownLabel       matlab.ui.control.Label
        SystemDropDown            matlab.ui.control.DropDown
        ErrorValueEditFieldLabel  matlab.ui.control.Label
        ErrorValueEditField       matlab.ui.control.NumericEditField
        ErrorTypeDropDownLabel    matlab.ui.control.Label
        ErrorTypeDropDown         matlab.ui.control.DropDown
        DrupalCheckBox            matlab.ui.control.CheckBox
        JoomlaCheckBox            matlab.ui.control.CheckBox
        ComparativeLabel          matlab.ui.control.Label
        GraficTab                 matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainData=table({}); % Dane odczytane
        DisplayData=table({});% Dane wyświetlane
        BackupData=table({});% Dane do obliczeń średniej
        CompData=table({});% Dane porównania systemów
        CellChosen=1; % Wybrany element tabeli
        Parameters % Parametry
        ErrorValue % Wartość błędu
        x % Wordpress wartości przybliżone
        y % Drupal wartości przybliżone
        z % Joomla wartości przybliżone
        ErrorType='1'; % Typ błędu
        Options=[1 1]; % Wybór trybu obliczeń
    end
    
    methods (Access = private)
        
        
        function [] = ShowData(app) % Przepisanie danych aplikacji do elementów je wyświetlających
            app.UITable.Data=app.DisplayData;
            app.UITable2.Data=app.MainData;
            names=char(app.MainData.Properties.VariableNames);
            app.UITable2.ColumnName=cellstr(names);
            app.UITable3.Data=app.CompData;
        end
        
        function newtable = GetDifTime(app) % wyznaczenie różnic czasowych między funkcjonalnościami
            data=app.MainData;
            table=table2array(data(:,[2 5 8])); % Pobranie dat
            s=size(table);
            newtable=zeros(s(1),s(2)); % inicjalizacja zerami
            for j=1:s(2)
                for i=2:s(1)
                    dif=between(table(i-1,j),table(i,j),{'Months','Days'}); % wyznaczenie różnicy między datami
                    [m,d]=split(dif,{'Months','Days'});
                    difnum=m+(d/30); %przybliżenie dni do miesięcy
                    newtable(i,j)=max(difnum,0);
                end
            end
        end
        function [newdata] = AverageData(app,number) % uśrednianie
            %number - wartość liczbowa do uśredniania, określa stopień uśrednienia
            %stopień 1 to po 2 wartości 2 to 3 i tak dalej
            dana=app.BackupData; % wyciągnięcie danych do uśrednienia
            n=number+1;
            s=size(dana);
            newdata=zeros(s(1),3); %dane zwrotne inicjuje zerami % przepisanie indeksów funkcjonalności
            for k=1:3
                for i=2:s(1) % pominięcie elementów początkowych
                    if i+ceil(-number/2)>=1 && i+ceil(number/2)<=s(1) % określenie czy można wykonać uśrednienie
                        tmp=0;
                        for j=ceil(-number/2):ceil(number/2) % suma po elementach otoczenia
                            tmp=tmp+dana(i+j,k);
                        end
                        newdata(i,k)=tmp/n; % średnia
                    else
                        newdata(i,k)=dana(i,k); % nie zmieniamy jeśli brakuje liczb(zachowanie krawędzi)
                    end
                end
            end
        end
        
        function results = GenerateCompData(app) %Generuje dane porównawcze między systemami
            table=table2array(app.MainData(:,[2 5 8]));
            kolekt=table2array(app.MainData(:,[3 6 9])); % segregacja funkcjonalności
            table(:,1)=table(kolekt(:,1),1);
            table(:,2)=table(kolekt(:,2),2);
            table(:,3)=table(kolekt(:,3),3);
            s=size(table);
            s1=factorial(s(2));
            newtable=zeros(s(1),s1);
            index=1;
            for j=1:s(2)
                for k=1:s(2)
                    if k~=j % wykluczenie sam do siebie
                        for i=1:s(1)
                            dif=between(table(i,j),table(i,k),'Months');
                            difnum=split(dif,'Months');
                            newtable(i,index)=abs(min(difnum,0)); % bez ujemnych wartości
                        end
                        index=index+1;
                    end
                end
            end
            results=[newtable(:,1),newtable(:,3),newtable(:,2),newtable(:,5),newtable(:,4),newtable(:,6)]; %segregacja
        end
        
        function [] = UpdatePlot(app) % Wyrysowanie danych na wykresach
            % Wykres 1
            global base;
            data=table2array(app.MainData(:,[2 5 8]));
            s=size(data);
            indexs=table2array(app.MainData(:,1));
            for i=1:s(2)
                plot(app.UIAxes,data(:,i),indexs,'-o','LineWidth',2);
                hold(app.UIAxes,'on');
            end
            legend(app.UIAxes,'Wordpress','Drupal','Joomla','Location',"southeast");
            hold(app.UIAxes,'off');
            % Wykres 2
            if (~isempty(app.UITable4.Data))
                plot(app.UIAxes2,app.x,'-o','LineWidth',2);
                hold(app.UIAxes2,'on');
                plot(app.UIAxes2,base,'-o','LineWidth',2);
                hold(app.UIAxes2,'off');
                legend(app.UIAxes2,'Apro','Base');
            end
        end
        
        function [] = UpdateData(app) % Wywołanie funkcji wyznaczających dane z danej głównej
            app.BackupData=GetDifTime(app);
            app.DisplayData=AverageData(app,app.DegreeSpinner.Value);
            app.CompData=GenerateCompData(app);
            UpdatePlot(app)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            [filename,path] = uigetfile('*.txt;*.csv;*.xlsx'); %wybór pliku
            if filename ~= 0 %plik istnieje?
                [~,~,filetype] = fileparts(filename); %typ pliku 
                file=strcat(path,filename); % wygenerowanie pełnej ścieżki pliku
                delimiterIn = '\t';
                dateformat= '{yyyy.MM.dd}';
                readformat=strcat('%f%',dateformat,'D%f%f%',dateformat,'D%f%f%',dateformat,'D%f%s');
                switch filetype
                    case '.txt' %działa
                        datatmp = readtable(file,'Delimiter',delimiterIn,'Format',readformat);
                    case '.csv' %działa
                        datatmp = readtable(file,'Format',readformat);
                    case '.xlsx' % działa tak sobie
                        datatmp = readtable(file);
                    otherwise %nie obsługiwany format pliku
                        f = warndlg('File type not suported','Error');
                end
            else %plik nie istnieje lub wystąpił błąd odczytu
                f = warndlg('File not found','Error');
            end
            app.MainData=datatmp;
            UpdateData(app);
            ShowData(app);
        end

        % Cell edit callback: UITable2
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.MainData(indices(1),indices(2))={newData}; %zmiania elementu danych głównych
        end

        % Value changed function: DegreeSpinner
        function DegreeSpinnerValueChanged(app, event)
            app.DisplayData=AverageData(app,app.DegreeSpinner.Value); % uśrednienie
            ShowData(app);
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            [filename,path] = uiputfile({'*.txt';'*.csv'});
            file=strcat(path,filename);
            writetable(app.MainData,file,'Delimiter',delimiterIn); % zapis
        end

        % Cell selection callback: UITable2
        function UITable2CellSelection(app, event)
              app.CellChosen = event.Indices; % pozycja w tabeli
%               c = uicontextmenu(app.UIFigure); nie działa dla UIFigure...
%               m1 = uimenu(c,'Label','Remove');
%               m2 = uimenu(c,'Label','Add');
        end

        % Value changed function: iSpinner
        function iSpinnerValueChanged(app, event)
            value = app.iSpinner.Value;
            app.UITable4.ColumnName=string([1:value]); % dostosowanie wielkości tabeli
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global base;
            global compy;
            global options;
            options=sum([1 2].*double(app.Options));
            system=1;
            global Etype;
            Etype=app.ErrorType;
            
            switch app.SystemDropDown.Value % Wyznaczenir wartości liczbowej(nie sprawdzałem czy jak wykonam konwersję na double to bd poprawnie)
                case '1'%'Wordpress'
                    system=1;
                case '2'%'Drupal'
                    system=2;
                case '3'%'Joomla'
                    system=3;
            end
            global compz;
            base=app.DisplayData(:,system);
            if (options==0) % selfimprove
                x0=zeros(1,app.iSpinner.Value);
                x0(1,1)=0.1;
                [app.Parameters,app.ErrorValue]=fminsearch(@opt,x0);
                app.x=optfcn(app.Parameters,base);
            else
                switch system
                    case 1
                        compy=app.CompData(:,1);
                        compz=app.CompData(:,3);
                    case 2
                        compy=app.CompData(:,2);
                        compz=app.CompData(:,5);
                    case 3
                        compy=app.CompData(:,4);
                        compz=app.CompData(:,6);
                end
                
                if (options<3) % comparative dla 2
                    x0=zeros(2,app.iSpinner.Value);
                    x0(1,1)=0.1;
                    [app.Parameters,app.ErrorValue]=fminsearch(@optcomp2,x0);
                else % comparative dla 3
                    x0=zeros(3,app.iSpinner.Value);
                    x0(1,1)=0.1;
                    [app.Parameters,app.ErrorValue]=fminsearch(@optcomp,x0);
                end
                app.x=optcompfcn(app.Parameters,base,compy,compz); % dane do wykresu
            end
            app.ErrorValueEditField.Value=app.ErrorValue;
            app.UITable4.Data=app.Parameters;
            UpdatePlot(app);
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Add row after chosen row?','Add Row','Icon','info');
                    if selection
                        if isempty(app.MainData)
                            
                        else
                            data=app.MainData(1:app.CellChosen,:);
                            tmp=table2array(data(end,1));
                            tmp1=[table(tmp+1),data(end,2:end)];
                            tmp1.Properties.VariableNames=data.Properties.VariableNames;
                            if app.CellChosen+1<=height(app.MainData)
                                app.MainData=[data;tmp1;app.MainData(app.CellChosen+1:end,:)];
                            else
                                app.MainData=[data;tmp1];
                            end
                        end
                    end
                    UpdateData(app);
                    ShowData(app);
        end

        % Button pushed function: RemoveRowButton
        function RemoveRowButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure,'Remove chosen row?','Remove Row','Icon','info');
                    if selection
                        if isempty(app.MainData)
                            
                        else
                            if app.CellChosen+1<=height(app.MainData)
                                app.MainData=[app.MainData(1:app.CellChosen-1,:);app.MainData(app.CellChosen+1:end,:)];
                            else
                                app.MainData=app.MainData(1:end-1,:);
                            end
                        end
                    end
                    UpdateData(app);
                    ShowData(app);
        end

        % Value changed function: ErrorTypeDropDown
        function ErrorTypeDropDownValueChanged(app, event)
            app.ErrorType = app.ErrorTypeDropDown.Value;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            UpdateData(app);
            ShowData(app);
        end

        % Value changed function: SystemDropDown
        function SystemDropDownValueChanged(app, event)
            value = app.SystemDropDown.Value;
            switch value
                case '1'%'Wordpress'
                    app.DrupalCheckBox.Text='Drupal';
                    app.JoomlaCheckBox.Text='Joomla';
                case '2'%'Drupal'
                    app.JoomlaCheckBox.Text='Joomla';
                    app.DrupalCheckBox.Text='Wordpress';
                case '3'%'Joomla'
                    app.DrupalCheckBox.Text='Wordpress';
                    app.JoomlaCheckBox.Text='Drupal';
            end
            
        end

        % Value changed function: DrupalCheckBox
        function DrupalCheckBoxValueChanged(app, event)
            value = app.DrupalCheckBox.Value;
            app.Options(1)=value;
        end

        % Value changed function: JoomlaCheckBox
        function JoomlaCheckBoxValueChanged(app, event)
            value = app.JoomlaCheckBox.Value;
            app.Options(2)=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Add', 'Remove'};
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Add';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup2);
            app.DataTab.Title = 'Data';
            app.TabGroup = uitabgroup(app.DataTab);
            app.AllDataTab = uitab(app.TabGroup);
            app.AllDataTab.Title = 'AllData';
            app.UITable2 = uitable(app.AllDataTab);
            app.UITable2.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable2.CellSelectionCallback = createCallbackFcn(app, @UITable2CellSelection, true);
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.UITable = uitable(app.FrequencyTab);
            app.UITable.ColumnName = {'Wordpress'; 'Drupal'; 'Joomla'};
            app.UITable.RowName = {};
            app.CompDataTab = uitab(app.TabGroup);
            app.CompDataTab.Title = 'CompData';
            app.UITable3 = uitable(app.CompDataTab);
            app.UITable3.ColumnName = {'Wordpress-Drupal'; 'Drupal-Wordpress'; 'Wordpress-Joomla'; 'Joomla-Wordpress'; 'Drupla-Joomla'; 'Joomla-Drupal'};
            app.UITable3.RowName = {};
            app.DataGraficTab = uitab(app.TabGroup);
            app.DataGraficTab.Title = 'DataGrafic';
            app.UIAxes = uiaxes(app.DataGraficTab);
            title(app.UIAxes, 'The course of the update')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amount of functionalities')
            app.DegreeSpinnerLabel = uilabel(app.DataTab);
            app.DegreeSpinnerLabel.Text = 'Degree';
            app.DegreeSpinner = uispinner(app.DataTab);
            app.DegreeSpinner.Limits = [0 5];
            app.DegreeSpinner.ValueChangedFcn = createCallbackFcn(app, @DegreeSpinnerValueChanged, true);
            app.averageLabel = uilabel(app.DataTab);
            app.averageLabel.Text = 'average';
            app.LoadfromfileButton = uibutton(app.DataTab, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Text = 'Load from file';
            app.SavetofileButton = uibutton(app.DataTab, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.AddRowButton = uibutton(app.DataTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'AddRow';
            app.RemoveRowButton = uibutton(app.DataTab, 'push');
            app.RemoveRowButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRowButtonPushed, true);
            app.RemoveRowButton.Text = 'RemoveRow';
            app.RefreshButton = uibutton(app.DataTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ParameterTab = uitab(app.TabGroup2);
            app.ParameterTab.Title = 'Parameter';
            app.UITable4 = uitable(app.ParameterTab);
            app.UITable4.ColumnName = {'1'};
            app.UITable4.RowName = {'a'; 'b'; 'c'};
            app.iSpinnerLabel = uilabel(app.ParameterTab);
            app.iSpinnerLabel.Text = 'i';
            app.iSpinner = uispinner(app.ParameterTab);
            app.iSpinner.Limits = [1 5];
            app.iSpinner.ValueChangedFcn = createCallbackFcn(app, @iSpinnerValueChanged, true);
            app.iSpinner.Value = 1;
            app.StartButton = uibutton(app.ParameterTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SystemDropDownLabel = uilabel(app.ParameterTab);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.ParameterTab);
            app.SystemDropDown.Items = {'Wordpress', 'Drupal', 'Joomla'};
            app.SystemDropDown.ItemsData = {'1', '2', '3'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownValueChanged, true);
            app.SystemDropDown.Value = '1';
            app.ErrorValueEditFieldLabel = uilabel(app.ParameterTab);
            app.ErrorValueEditFieldLabel.Text = 'Error Value';
            app.ErrorValueEditField = uieditfield(app.ParameterTab, 'numeric');
            app.ErrorTypeDropDownLabel = uilabel(app.ParameterTab);
            app.ErrorTypeDropDownLabel.Text = 'Error Type';
            app.ErrorTypeDropDown = uidropdown(app.ParameterTab);
            app.ErrorTypeDropDown.Items = {'e^2', '|e|_sr', 'max(|e|)', 'integral(|e|)'};
            app.ErrorTypeDropDown.ItemsData = {'1', '2', '3', '4'};
            app.ErrorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ErrorTypeDropDownValueChanged, true);
            app.ErrorTypeDropDown.Value = '1';
            app.DrupalCheckBox = uicheckbox(app.ParameterTab);
            app.DrupalCheckBox.ValueChangedFcn = createCallbackFcn(app, @DrupalCheckBoxValueChanged, true);
            app.DrupalCheckBox.Text = 'Drupal';
            app.DrupalCheckBox.Value = true;
            app.JoomlaCheckBox = uicheckbox(app.ParameterTab);
            app.JoomlaCheckBox.ValueChangedFcn = createCallbackFcn(app, @JoomlaCheckBoxValueChanged, true);
            app.JoomlaCheckBox.Text = 'Joomla';
            app.JoomlaCheckBox.Value = true;
            app.ComparativeLabel = uilabel(app.ParameterTab);
            app.ComparativeLabel.Text = 'Comparative';
            app.GraficTab = uitab(app.TabGroup2);
            app.GraficTab.Title = 'Grafic';
            app.UIAxes2 = uiaxes(app.GraficTab);
            title(app.UIAxes2, 'Comparison')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end