classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DonotPressButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: DonotPressButton
        function DonotPressButtonPushed(app, event)
            [y, Fs] = audioread('jonasz.mp3');
            sound(y, Fs);
            fullFileName = 'duck.gif';
            [gifImage, cmap] = imread(fullFileName, 'Frames', 'all');
            [~, ~, ~, numImages] = size(gifImage);
            % Construct an RGB movie.
            title('WHAT HAVE YOU DONE','Parent',app.UIAxes);
            k = 1;
            while k <= numImages
              thisFrame = gifImage(:,:,:, k);
              thisRGB = uint8(255 * ind2rgb(thisFrame, cmap));
              imshow(thisRGB,'Parent',app.UIAxes);
              drawnow;
              k = k+1;
              if k == numImages
                  k=1;
              end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DonotPressButton = uibutton(app.UIFigure, 'push');
            app.DonotPressButton.ButtonPushedFcn = createCallbackFcn(app, @DonotPressButtonPushed, true);
            app.DonotPressButton.Text = 'Do not Press';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end